classdef CCFregister < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SavepointsButton            matlab.ui.control.Button
        DeletepointButton           matlab.ui.control.Button
        bsplineWarpButton           matlab.ui.control.Button
        AffineWarpButton            matlab.ui.control.Button
        AddpointButton              matlab.ui.control.Button
        CCFSettingsPanel            matlab.ui.container.Panel
        OverlayAnnoCheckBox         matlab.ui.control.CheckBox
        CCFtt                       matlab.ui.control.TextArea
        SelectCCFVolumeButtonGroup  matlab.ui.container.ButtonGroup
        AverageTemplateButton       matlab.ui.control.RadioButton
        AnnotationBoundaryButton    matlab.ui.control.RadioButton
        AnnotationButton            matlab.ui.control.RadioButton
        ColorCCFbyButtonGroup       matlab.ui.container.ButtonGroup
        SubstructureButton          matlab.ui.control.RadioButton
        StructureButton             matlab.ui.control.RadioButton
        DivisionButton              matlab.ui.control.RadioButton
        CategoryButton              matlab.ui.control.RadioButton
        OrganButton                 matlab.ui.control.RadioButton
        CCFPanel                    matlab.ui.container.Panel
        CCFZSlider                  matlab.ui.control.Slider
        CCFaxes                     matlab.ui.control.UIAxes
        ptsTable                    matlab.ui.control.Table
        FixedPanel                  matlab.ui.container.Panel
        SampleZSlider               matlab.ui.control.Slider
        SampleImageParamsPanel      matlab.ui.container.Panel
        AutosetlimitsCheckBox       matlab.ui.control.CheckBox
        MaxEditField                matlab.ui.control.NumericEditField
        MaxEditFieldLabel           matlab.ui.control.Label
        MinEditField                matlab.ui.control.NumericEditField
        MinEditFieldLabel           matlab.ui.control.Label
        FixedAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        abcDir % ABC atlas directory
        imgDir % Allen CCF directory
        colorDir % Allen CCF colors directory
        ccfColors % path to Allen CCF colors csv
        ccfTerms % path to Allen CCF parcellation terms csv
        Tcolors % table that stores Allen CCF color data
        Tterms % table that stores Allen CCF parcellation terms data
        colors % current Allen CCF colormap

        anno_orig
        anno_bound_orig
        avg_temp_orig
        anno
        anno_bound
        avg_temp

        ttOrgan
        ttCategory
        ttDivision
        ttStructure
        ttSubstructure
        CCFmarker
        
        im
        im_info
        CCF_im

        zCCF
        zSamp

        points
        pts
        
    end
    
    methods (Access = private)
        
        function loadCCFvol(app)

            function vol = rotateVol(vol)
                vol = imrotate3(vol,-90,[0 0 1]);
                vol = imrotate3(vol,-90,[0 1 0]);
            end
            
            app.anno = niftiread(fullfile(app.imgDir,'annotation_10.nii'));
            app.anno_bound = niftiread(fullfile(app.imgDir,'annotation_boundary_10.nii'));
            app.avg_temp = niftiread(fullfile(app.imgDir,'average_template_10.nii'));

            app.anno = rotateVol(app.anno);
            app.anno_bound = rotateVol(app.anno_bound);
            app.avg_temp = rotateVol(app.avg_temp);

            app.anno_orig = app.anno;
            app.anno_bound_orig = app.anno_bound;
            app.avg_temp_orig = app.avg_temp;
            
        end

        function setCCFcolormap(app)
            
            if strcmp(app.SelectCCFVolumeButtonGroup.SelectedObject.Text,'Annotation')

                switch app.ColorCCFbyButtonGroup.SelectedObject.Text
                    case 'Organ'
                        app.colors = hex2rgb(app.Tcolors{:,'organ_color'});
                    case 'Category'
                        app.colors = hex2rgb(app.Tcolors{:,'category_color'});
                    case 'Division'
                        app.colors = hex2rgb(app.Tcolors{:,'division_color'});
                    case 'Structure'
                        app.colors = hex2rgb(app.Tcolors{:,'structure_color'});
                    case 'Substructure'
                        app.colors = hex2rgb(app.Tcolors{:,'substructure_color'});
                end

            else

                app.colors = gray(517);

            end

        end

        function updateCCFvol(app)

            X = app.anno(:,:,app.zCCF);
            AlphaData = zeros(size(X));
            AlphaData(X ~= 0) = 1;

            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text

                case 'Annotation'

                    C = unique(X);
                    cmap = app.colors(ismember(app.Tcolors.parcellation_index,C),:);
                    d = dictionary(C',1:length(C));
                    X = d(X);
                    RGB = ind2rgb(X,cmap);

                    if app.OverlayAnnoCheckBox.Value == true
                        BW = imbinarize(app.anno_bound(:,:,app.zCCF),0);
                        RGB = imoverlay(RGB,BW,"black");
                    end
                    
                    app.CCF_im = imshow(RGB,Parent=app.CCFaxes);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

                case 'Annotation Boundary'

                    app.CCF_im = imshow(app.anno_bound(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=app.colors);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

                case 'Average Template'

                    app.CCF_im = imshow(app.avg_temp(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=app.colors,DisplayRange=[0,516]);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

            end

            app.CCF_im.AlphaData = AlphaData;

        end

        function clickCCFCallback(app,~,hit)

            x = uint16(hit.IntersectionPoint(2));
            y = uint16(hit.IntersectionPoint(1));
            hold(app.CCFaxes,"on")
            delete(app.CCFmarker)
            app.CCFmarker = scatter(app.CCFaxes,y,x,40,"r+");
            hold(app.CCFaxes,"off")
            parc_ind = app.anno(x,y,app.zCCF);
            rows = (app.Tterms.parcellation_index == parc_ind);
            row = app.Tterms(rows,:);
            app.ttOrgan = row.organ{1};
            app.ttCategory = row.category{1};
            app.ttDivision = row.division{1};
            app.ttStructure = row.structure{1};
            app.ttSubstructure = row.substructure{1};
            app.updateCCFtt()

        end
        
        function updateCCFtt(app)

            formatSpec = "Organ: %s | Category: %s | Division: %s | Structure: %s | Substructure: %s";
            str = sprintf(formatSpec,app.ttOrgan,app.ttCategory, ...
                app.ttDivision,app.ttStructure,app.ttSubstructure);
            app.CCFtt.Value = str;

        end

        function calibrateZSlider(~, ZSlider, vol)

            numZ = uint16(size(vol,3));
            ZSlider.Limits = [double(1) double(numZ)];
            ZSlider.MajorTicks = [1 numZ];
            ZSlider.MajorTickLabels = {'1' num2str(numZ)};
            ZSlider.MinorTicks = [];
            ZSlider.Value = double(median(1:numZ));

        end

        function fetchZ(app)
            app.zCCF = uint16(app.CCFZSlider.Value);
            app.zSamp = uint16(app.SampleZSlider.Value);
        end

        function updateSampleImage(app)

            imshow(app.im(:,:,app.zSamp),Parent=app.FixedAxes,InitialMagnification="fit");

        end

        function autoSetLims(app, value)

            switch value

                case true

                    app.MaxEditField.Editable = false;
                    app.MinEditField.Editable = false;

                    P = prctile(app.im(:,:,app.zSamp),[0.1, 99.9],"all");
                    app.MaxEditField.Value = double(P(2));
                    app.MinEditField.Value = double(P(1));
                    app.FixedAxes.CLim = P;
                    
                case false

                    app.MaxEditField.Editable = true;
                    app.MinEditField.Editable = true;
            end

        end

        function loadSampleImage(app)

            % [file, location] = uigetfile('*.tiff;*.tif','Select tiff file');
            file = 'r1_neur445.tif';
            location = 'D:\2025-10-18_contra_1\post\core_output\s04\';
            [app.im, app.im_info] = read_tiff(fullfile(location,file));
            
            app.calibrateZSlider(app.SampleZSlider,app.im)
            app.fetchZ()
            app.updateSampleImage()
            app.autoSetLims(app.AutosetlimitsCheckBox.Value)

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % add subdirectories with needed functions
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])

            movegui(app.UIFigure,'north')
            
            % setting up Allen CCF stuff
            app.abcDir = params('abcDir');
            app.imgDir = fullfile(app.abcDir,'image_volumes', ...
                'Allen-CCF-2020','20230630');
            app.colorDir = fullfile(app.abcDir,'metadata', ...
                'Allen-CCF-2020','20230630','views');

            app.ccfColors = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_color.csv');
            app.ccfTerms = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_acronym.csv');

            app.Tcolors = readtable(app.ccfColors);
            app.Tterms = readtable(app.ccfTerms);

            axis(app.CCFaxes,"off")
            axis(app.CCFaxes,"image")
            axis(app.FixedAxes,"off")
            axis(app.FixedAxes,"image")

            app.loadSampleImage()
            
            app.loadCCFvol()
            app.setCCFcolormap()
            app.calibrateZSlider(app.CCFZSlider,app.anno)
            app.fetchZ()
            app.updateCCFvol()

            app.points(1) = drawpoint(app.FixedAxes, 'Color', 'g', 'Position', ...
                [round(size(app.im, 2)./2), round(size(app.im, 1)./2)]);
            app.points(2) = drawpoint(app.CCFaxes, 'Color', 'm', 'Position', ...
                [round(size(app.anno, 2)./2), round(size(app.anno, 1)./2)]);

            app.pts.fix = [];
            app.pts.mov = [];
            app.pts.cur = [];
        end

        % Selection changed function: SelectCCFVolumeButtonGroup
        function SelectCCFVolumeButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            app.updateCCFvol()
        end

        % Selection changed function: ColorCCFbyButtonGroup
        function ColorCCFbyButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            app.updateCCFvol()
        end

        % Value changed function: OverlayAnnoCheckBox
        function OverlayAnnoCheckBoxValueChanged(app, event)
            app.updateCCFvol()
        end

        % Value changed function: CCFZSlider
        function CCFZSliderValueChanged(app, event)
            app.zCCF = uint16(app.CCFZSlider.Value);
            app.updateCCFvol()
        end

        % Value changed function: AutosetlimitsCheckBox
        function AutosetlimitsCheckBoxValueChanged(app, event)
            value = app.AutosetlimitsCheckBox.Value;
            app.autoSetLims(value)
        end

        % Value changed function: SampleZSlider
        function SampleZSliderValueChanged(app, event)
            app.zSamp = uint16(app.SampleZSlider.Value);
            app.updateSampleImage()
            app.autoSetLims(app.AutosetlimitsCheckBox.Value)
        end

        % Button pushed function: AddpointButton
        function AddpointButtonPushed(app, event)
            pSample = app.points(1).Position;
            pCCF = app.points(2).Position;

            app.pts.fix(end+1, :) = [pSample app.zSamp];
            app.pts.cur(end+1, :) = [pCCF app.zCCF];

        end

        % Cell selection callback: ptsTable
        function ptsTableCellSelection(app, event)
            indices = event.Indices;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FixedPanel = uipanel(app.UIFigure);
            app.FixedPanel.Title = 'Fixed';
            app.FixedAxes = uiaxes(app.FixedPanel);
            app.SampleImageParamsPanel = uipanel(app.FixedPanel);
            app.SampleImageParamsPanel.Title = 'Sample Image Display Parameters';
            app.MinEditFieldLabel = uilabel(app.SampleImageParamsPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.SampleImageParamsPanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.SampleImageParamsPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.SampleImageParamsPanel, 'numeric');
            app.AutosetlimitsCheckBox = uicheckbox(app.SampleImageParamsPanel);
            app.AutosetlimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutosetlimitsCheckBoxValueChanged, true);
            app.AutosetlimitsCheckBox.Text = 'Auto set limits';
            app.AutosetlimitsCheckBox.Value = true;
            app.SampleZSlider = uislider(app.FixedPanel);
            app.SampleZSlider.ValueChangedFcn = createCallbackFcn(app, @SampleZSliderValueChanged, true);
            app.ptsTable = uitable(app.UIFigure);
            app.ptsTable.ColumnName = {''; 'x fixed'; 'y fixed'; 'z fixed'};
            app.ptsTable.RowName = {};
            app.ptsTable.CellSelectionCallback = createCallbackFcn(app, @ptsTableCellSelection, true);
            app.CCFPanel = uipanel(app.UIFigure);
            app.CCFPanel.Title = 'Allen CCF';
            app.CCFaxes = uiaxes(app.CCFPanel);
            app.CCFZSlider = uislider(app.CCFPanel);
            app.CCFZSlider.ValueChangedFcn = createCallbackFcn(app, @CCFZSliderValueChanged, true);
            app.ColorCCFbyButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorCCFbyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorCCFbyButtonGroupSelectionChanged, true);
            app.ColorCCFbyButtonGroup.Title = 'Color CCF by:';
            app.OrganButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.OrganButton.Text = 'Organ';
            app.CategoryButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.CategoryButton.Text = 'Category';
            app.DivisionButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.DivisionButton.Text = 'Division';
            app.StructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.StructureButton.Text = 'Structure';
            app.StructureButton.Value = true;
            app.SubstructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.SubstructureButton.Text = 'Substructure';
            app.SelectCCFVolumeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectCCFVolumeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectCCFVolumeButtonGroupSelectionChanged, true);
            app.SelectCCFVolumeButtonGroup.Title = 'Select CCF Volume:';
            app.AnnotationButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationButton.Text = 'Annotation';
            app.AnnotationButton.Value = true;
            app.AnnotationBoundaryButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationBoundaryButton.Text = 'Annotation Boundary';
            app.AverageTemplateButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AverageTemplateButton.Text = 'Average Template';
            app.CCFtt = uitextarea(app.UIFigure);
            app.CCFtt.Value = {'Click anywhere in the CCF to view metadata'};
            app.CCFSettingsPanel = uipanel(app.UIFigure);
            app.CCFSettingsPanel.Title = 'CCF Settings';
            app.OverlayAnnoCheckBox = uicheckbox(app.CCFSettingsPanel);
            app.OverlayAnnoCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayAnnoCheckBoxValueChanged, true);
            app.OverlayAnnoCheckBox.Text = {'Overlay annotation boundaries'; 'on annotation volume?'};
            app.OverlayAnnoCheckBox.Value = true;
            app.AddpointButton = uibutton(app.UIFigure, 'push');
            app.AddpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointButtonPushed, true);
            app.AddpointButton.Text = 'Add point';
            app.AffineWarpButton = uibutton(app.UIFigure, 'push');
            app.AffineWarpButton.Text = 'Affine Warp';
            app.bsplineWarpButton = uibutton(app.UIFigure, 'push');
            app.bsplineWarpButton.Text = 'b-spline Warp';
            app.DeletepointButton = uibutton(app.UIFigure, 'push');
            app.DeletepointButton.Text = 'Delete point';
            app.SavepointsButton = uibutton(app.UIFigure, 'push');
            app.SavepointsButton.Text = 'Save points';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCFregister

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end