classdef HCRprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Switch                        matlab.ui.control.Switch
        ProcessingPipelinePanel       matlab.ui.container.Panel
        FinalSwitch                   matlab.ui.control.ToggleSwitch
        FinalLabel                    matlab.ui.control.Label
        FinalLamp                     matlab.ui.control.Lamp
        Image_5                       matlab.ui.control.Image
        Image_4                       matlab.ui.control.Image
        SegSwitch                     matlab.ui.control.ToggleSwitch
        Image_3                       matlab.ui.control.Image
        UnmixSwitch                   matlab.ui.control.ToggleSwitch
        LipoSwitch                    matlab.ui.control.ToggleSwitch
        RegSwitch                     matlab.ui.control.ToggleSwitch
        Image_2                       matlab.ui.control.Image
        SegmentationLabel             matlab.ui.control.Label
        UnmixingLabel                 matlab.ui.control.Label
        LipoGoneLabel                 matlab.ui.control.Label
        StitchSwitch                  matlab.ui.control.ToggleSwitch
        RegistrationLabel             matlab.ui.control.Label
        StitchingLabel                matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        SegLamp                       matlab.ui.control.Lamp
        UnmixLamp                     matlab.ui.control.Lamp
        LipoLamp                      matlab.ui.control.Lamp
        RegLamp                       matlab.ui.control.Lamp
        StitchLamp                    matlab.ui.control.Lamp
        memMonitorButton              matlab.ui.control.Button
        DirTree                       matlab.ui.container.Tree
        ExperimentDirectoryNode       matlab.ui.container.TreeNode
        ExpDirButton                  matlab.ui.control.Button
        RegRoundDropDown              matlab.ui.control.DropDown
        RegistrationRoundLabel        matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        SettingsTable                 matlab.ui.control.Table
        FilesTable                    matlab.ui.control.Table
        StitchingTab                  matlab.ui.container.Tab
        StitchRoundLabel              matlab.ui.control.Label
        StitchTable                   matlab.ui.control.Table
        StitchButton                  matlab.ui.control.Button
        StitchAllButton               matlab.ui.control.Button
        RegTab                        matlab.ui.container.Tab
        UseDefaultParametersCheckBox  matlab.ui.control.CheckBox
        RegButton                     matlab.ui.control.Button
        RegRoundLabel                 matlab.ui.control.Label
        FiducialsButton               matlab.ui.control.Button
        RegTable                      matlab.ui.control.Table
        FiducialsTable                matlab.ui.control.Table
        LipofuscinRemovalTab          matlab.ui.container.Tab
        LipoRoundLabel                matlab.ui.control.Label
        SpectralUnmixingTab           matlab.ui.container.Tab
        UnmixAllButton                matlab.ui.control.Button
        UnmixButton                   matlab.ui.control.Button
        UnmixParamButton              matlab.ui.control.Button
        UnmixTable                    matlab.ui.control.Table
        UnmixParamTable               matlab.ui.control.Table
        UnmixRoundLabel               matlab.ui.control.Label
        SegmentationTab               matlab.ui.container.Tab
        FinalTab                      matlab.ui.container.Tab
        ProcessButton                 matlab.ui.control.Button
        ProcessedTable                matlab.ui.control.Table
    end

    
    properties (Access = public)

        exp_dir                            % experiment directory
        ignore_kw = ["ROI", ... 
            "overview", ...
            "TileConfiguration", ...
            "reg", ...
            "ignore"];                     % keywords that mark file to be ignored
        reg_round_token = 'r1'             % token that marks the registration round
        reg_channel_token = ["NT", ...
            "Snap25"]                      % token(s) that mark registration channel

        stitch_sel                         % selected row in stitch table
        fiducials_sel                      % selected row in fiducials table
        reg_sel                            % selected row in registration table
        unmix_param_sel                    % selected row in unmix parameters table
        unmix_sel                          % selected row in unmixing table
        process_sel                        % selected row in final processing table

        reg_round                          % registration round

        round_folder                       % currently selected round folder
        T1                                 % table 1 (files table)
        T2                                 % table 2 (settings table)

        tables                             % cell array of all tables throughout the app 

        pipeline                           % cell array of strings describing processing steps to do

        num_uniq                           % number of unique images (slices typically)
        num_process_steps                  % total number of possible processing steps
        num_manual_steps                   % total number of possible manual steps

    end
    
    methods (Access = private)
        
        % populate internal folders, only needs to run once on startup
        function pop_int_folders(app, listing)
            
            % if the 'final' folder has already been created in the
            % experiment directory, don't include it while iterating
            % through rounds
            for i=1:length(listing)
                if strcmp(listing{i},'final')
                    listing(i) = [];
                end
            end

            % iterate through rounds
            for i=1:length(listing)
                roundf = listing{i};
                check_internal = fullfile(app.exp_dir,roundf,'internal');
                check_build_dir(check_internal)
                check_stitch = fullfile(app.exp_dir,roundf,'stitch');
                check_build_dir(check_stitch)
                check_reg = fullfile(app.exp_dir,roundf,'reg');
                check_build_dir(check_reg)
                check_unmix = fullfile(app.exp_dir,roundf,'unmix');
                check_build_dir(check_unmix)
                check_fiducials = fullfile(app.exp_dir,roundf,'internal','fiducials');
                check_build_dir(check_fiducials)
                check_unmix_param = fullfile(app.exp_dir,roundf,'internal','uparams');
                check_build_dir(check_unmix_param)
            end
            
            check_final = fullfile(app.exp_dir,'final');
            if ~exist(check_final, 'dir')
            
                check_build_dir(check_final)
    
                % extract cell array of slice names
                listing = fetch_dir(fullfile(app.exp_dir,roundf,'raw'), 'file');
                listing = filt_kw(listing, app.ignore_kw);
                
                % iterate through slices
                for i=1:length(listing)
                    split_str = split(listing{i},'__');
                    check_final_slice = fullfile(app.exp_dir,'final',split_str{1});
                    check_build_dir(check_final_slice)
                end

            end

        end
        
        function update_round(app)

            listing = fetch_dir(fullfile(app.exp_dir,app.round_folder, ...
                'internal'), 'file');

            % inherit pre-existing status tables if they exist
            if any(strcmp(listing,'T1.txt'))
                app.T1 = read_table_with_row_names(fullfile(app.exp_dir,app.round_folder,'internal','T1.txt'));
                app.T2 = read_table_with_row_names(fullfile(app.exp_dir,app.round_folder,'internal','T2.txt'));

            % or else make new ones
            else
                create_status_tables(app)
            end

            app.num_uniq = size(app.T1,1);
            app.num_process_steps = size(app.T1,2);
            app.num_manual_steps = size(app.T2,2);

            update_tables(app)
            update_round_labels(app)

        end

        function update_tables(app)

            row_names = app.T1.Properties.RowNames;
            app.FilesTable.RowName = row_names;
            app.SettingsTable.RowName = row_names;
            app.StitchTable.RowName = row_names;
            app.RegTable.RowName = row_names;
            app.FiducialsTable.RowName = row_names;
            app.UnmixTable.RowName = row_names;
            app.UnmixParamTable.RowName = row_names;
            app.ProcessedTable.RowName = row_names;
            
            app.FilesTable.Data = cell(app.num_uniq,app.num_process_steps);
            app.SettingsTable.Data = cell(app.num_uniq,app.num_manual_steps);
            app.StitchTable.Data = cell(app.num_uniq,1);
            app.RegTable.Data = cell(app.num_uniq,1);
            app.FiducialsTable.Data = cell(app.num_uniq,1);
            app.ProcessedTable.Data = cell(app.num_uniq,1);
            app.UnmixTable.Data = cell(app.num_uniq,1);
            app.UnmixParamTable.Data = cell(app.num_uniq,1);
            
            
            app.update_table_visual(table2array(app.T1),app.FilesTable)
            app.update_table_visual(table2array(app.T2),app.SettingsTable)
            app.update_table_visual(app.T1.Stitched,app.StitchTable)
            app.update_table_visual(app.T1.Registered,app.RegTable)
            app.update_table_visual(app.T2.Fiducials,app.FiducialsTable)
            app.update_table_visual(app.T1.Final,app.ProcessedTable)
            app.update_table_visual(app.T1.Unmixed,app.UnmixTable)
            app.update_table_visual(app.T2.("Unmix Settings"),app.UnmixParamTable)


        end

        function write_tables(app)

            writetable(app.T1, fullfile(app.exp_dir,app.round_folder,'internal','T1.txt'), ...
                    'WriteRowNames',true);
            writetable(app.T2, fullfile(app.exp_dir,app.round_folder,'internal','T2.txt'), ...
                    'WriteRowNames',true);

        end
        
        % build the full file name of an image given its unique identifier
        % and subfolder
        function img_f = build_img_f(app, img, subfolder, roundf)
            
            if ~exist('roundf','var')
                roundf = app.round_folder;
            end
            listing = fetch_dir(fullfile(app.exp_dir,roundf,subfolder),'file');
            listing = filt_kw(listing, app.ignore_kw);

            if sum(contains(listing, img)) == 0
                img_f = [];
                return
            end
            full_img = listing{contains(listing, img)};
            img_f = fullfile(app.exp_dir,roundf,subfolder,full_img);
           
        end
        
        % pick the registration round (round that other rounds will be
        % registered to)
        function init_reg_round(app)
            for i=1:length(app.ExperimentDirectoryNode.Children)
                app.RegRoundDropDown.Items{end+1} = app.ExperimentDirectoryNode.Children(i).Text;
                if contains(app.ExperimentDirectoryNode.Children(i).Text, app.reg_round_token)
                    app.RegRoundDropDown.Value = app.ExperimentDirectoryNode.Children(i).Text;
                end
            end
            app.reg_round = app.RegRoundDropDown.Value;
        end

        function update_round_labels(app)
            app.StitchRoundLabel.Text = app.round_folder;
            app.RegRoundLabel.Text = app.round_folder;
            app.LipoRoundLabel.Text = app.round_folder;
            app.UnmixRoundLabel.Text = app.round_folder;
        end
        
        % create new status tables
        function create_status_tables(app)
            listing = fetch_dir(fullfile(app.exp_dir,app.round_folder,'raw'), 'file');
            listing = filt_kw(listing, app.ignore_kw);
            
            % make sure there are raw files in this round folder
            if length(listing) < 1
                app.T1 = [];
                app.T2 = [];
                update_tables(app)
                msgbox("No raw files or status tables found in this round folder")
                return
            end

            nfiles = length(listing);
            for i=1:nfiles
                split_listing = split(listing{i},'__');
                listing{i} =  split_listing{1};
            end
            
            app.T1 = table(zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                'VariableNames',{'Stitched' 'Registered' 'Unmixed' 'Masked', 'Final'}, ...
                'RowNames',listing);

            if app.round_folder == app.reg_round
                app.T1.Registered = ones(length(app.T1.Registered), 1);
            end

            app.T2 = table(zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                'VariableNames',{'Fiducials' 'Unmix Settings'}, ...
                'RowNames',listing);

            write_tables(app)
        end
        
        % takes in file name and returns index of registration channel
        % according to preset registration channel token
        function ind = id_reg_channel(app,fn)

            split_fn = split(fn,'__');
            chans = split_fn{2};
            split_chans = split(chans,'_');

            for i=1:length(split_chans)

                if contains(split_chans{i},app.reg_channel_token,'IgnoreCase',true)
                    ind = i;
                    return
                end
            end
        end

        function update_table_visual(~, backend_arr, frontend_table)

            g_box = uistyle('BackgroundColor','green', ...
                'Icon','success', ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            r_box = uistyle('BackgroundColor','red', ...
                'Icon',fullfile('./','assets','Cross_red_circle.svg'), ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');

           
                nrows = size(frontend_table.Data,1);
                ncols = size(frontend_table.Data,2);

                for j=1:nrows
                    for k=1:ncols
                        switch backend_arr(j,k)
                            case 0
                                addStyle(frontend_table,r_box,'cell',[j,k])
                            case 1
                                addStyle(frontend_table,g_box,'cell',[j,k])
                            otherwise
                                continue
                        end
                    end
                end
               
        end


        % called when loading and when switches change
        function update_pipeline(app)

            app.pipeline = {};

            function check_switch(app, pipeline_switch, lamp, pipeline_id)
                if strcmp(pipeline_switch.Value,'On')
                    app.pipeline{end+1} = pipeline_id;
                    lamp.Color = [0 1 0];
                else
                    lamp.Color = [0.8 0.8 0.8];
                end
            end
            
            check_switch(app,app.StitchSwitch,app.StitchLamp,'stitch')
            check_switch(app,app.RegSwitch,app.RegLamp,'reg')
            check_switch(app,app.LipoSwitch,app.LipoLamp,'lipo')
            check_switch(app,app.UnmixSwitch,app.UnmixLamp,'unmix')
            check_switch(app,app.SegSwitch,app.SegLamp,'seg')
            check_switch(app,app.FinalSwitch,app.FinalLamp,'final')

        end

    end
    
    
    methods (Access = public)
        
        function finish_fiducials(app)
            
            fid_sel_idx = find(strcmp(app.FiducialsTable.RowName, app.fiducials_sel));
            app.T2(fid_sel_idx,:).Fiducials = 1;
            update_tables(app)
            write_tables(app)
            
        end

        function finish_unmix_params(app)

            unmix_param_sel_idx = find(strcmp(app.UnmixParamTable.RowName, app.unmix_param_sel));
            app.T2(unmix_param_sel_idx,:).("Unmix Settings") = 1;
            update_tables(app)
            write_tables(app)

        end

        function finish_unmix(app)

            unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, app.unmix_sel));
            app.T1(unmix_sel_idx,:).Unmixed = 1;
            update_tables(app)
            write_tables(app)

        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.UIFigure.Name = 'HCRprocess';
            app.tables = {app.FilesTable, app.SettingsTable, app.StitchTable, ...
                app.RegTable, app.FiducialsTable, app.UnmixTable, app.UnmixParamTable, ...
                app.ProcessedTable};

            % set up paths
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            folderName = params('fijiScriptsDir');
            addpath(folderName)
    
            % set experiment directory
            filename = fullfile(pwd,'internal','previous_exp_dir.mat');
            load(filename, 'previous_exp_dir')
            list_dir = dir(previous_exp_dir);
            if isempty(list_dir)
                app.exp_dir = uigetdir();
                previous_exp_dir = app.exp_dir;
                save(fullfile(pwd,'internal','previous_exp_dir.mat'), 'previous_exp_dir')
            else
                app.exp_dir = previous_exp_dir;
            end
            app.ExperimentDirectoryNode.Text = app.exp_dir;
        
            % populate subfolders (rounds)
            listing = fetch_dir(app.exp_dir, 'dir');

            % if the 'final' folder has already been created in the
            % experiment directory, don't include it while iterating
            % through rounds
            mask = ones(1,length(listing),'logical');
            for i=1:length(listing)
                if strcmp(listing{i},'final')
                    mask(i) = 0;
                end
            end
            listing = listing(mask);

            for i=1:length(listing)
                node = uitreenode(app.ExperimentDirectoryNode,'Text',listing{i}); %#ok<NASGU>
            end
            
            % set registration round
            init_reg_round(app);

            % populate internal folders
            pop_int_folders(app, listing);
            
            % expand experiment directory and select first round (r0)
            expand(app.ExperimentDirectoryNode)
            app.DirTree.SelectedNodes = app.ExperimentDirectoryNode.Children(1);
            app.round_folder = app.DirTree.SelectedNodes(1).Text;
            update_round(app);
            
        end

        % Button pushed function: ExpDirButton
        function ExpDirButtonPushed(app, event)
            for i=1:length(app.ExperimentDirectoryNode.Children)
                app.ExperimentDirectoryNode.Children(1).delete
            end
            previous_exp_dir = '';
            save(fullfile(pwd,'internal','previous_exp_dir.mat'), 'previous_exp_dir')
            app.startupFcn()
        end

        % Selection changed function: DirTree
        function DirTreeSelectionChanged(app, event)
            selectedNodes = app.DirTree.SelectedNodes;
            app.round_folder = selectedNodes(1).Text;
            update_round(app);
        end

        % Button pushed function: StitchButton
        function StitchButtonPushed(app, event)
            img_f = build_img_f(app, app.stitch_sel, 'raw');
            stitch_sel_idx = find(strcmp(app.StitchTable.RowName, app.stitch_sel));
            if app.T1(stitch_sel_idx,:).Stitched == 1
                msgbox("This image has already been stitched")
                return
            else
                d = uiprogressdlg(app.UIFigure,'Title','Stitching Progress',...
                    'Message','(0/1 files) Stitching...');
                d.Value = 0;
                file_frac = '(0/1 files)';
                stitch(img_f, d, file_frac)

                app.T1(stitch_sel_idx,:).Stitched = 1;
                update_tables(app)
                write_tables(app)

                d.Value = 1;
                close(d)
            end
        end

        % Button pushed function: StitchAllButton
        function StitchAllButtonPushed(app, event)
            to_stitch = ~app.T1.Stitched;
            if sum(to_stitch) < 1
                msgbox("All images have already been stitched")
                return
            end

            stitch_sels = app.StitchTable.RowName(to_stitch);
            
            max_files = sum(to_stitch);
            file_frac = strcat('(0/',string(max_files),' files)');
            d = uiprogressdlg(app.UIFigure,'Title','Stitching Progress',...
                    'Message',strcat(file_frac,' Stitching...'));
            d.Value = 0;
            
            
            for i=1:length(stitch_sels)

                file_frac = strcat('(',string(i-1),'/',string(max_files),' files)');
                d.Message = strcat(file_frac,' Stitching...');

                img_f = build_img_f(app, stitch_sels(i), 'raw');
                stitch_sel_idx = find(strcmp(app.StitchTable.RowName, stitch_sels(i)));
                
                stitch(img_f, d, file_frac)

                app.T1(stitch_sel_idx,:).Stitched = 1;
                update_tables(app)
                write_tables(app)

                d.Value = i/max_files;
                
            end

        end

        % Button pushed function: FiducialsButton
        function FiducialsButtonPushed(app, event)
            
            fid_sel_idx = find(strcmp(app.FiducialsTable.RowName, app.fiducials_sel));
            if app.T2(fid_sel_idx,:).Fiducials == 1
                msgbox("Fiducials have already been marked for this file")
                return
            end

            moving_fn = app.build_img_f(app.fiducials_sel, 'stitch');

            if isempty(moving_fn)
                msgbox("Selected stitched moving image does not exist")
                return
            end

            fixed_fn = app.build_img_f(app.fiducials_sel, 'stitch', app.reg_round);

            if isempty(fixed_fn)
                msgbox("Selected stitched fixed image does not exist")
                return
            end

            [~,name,~] = fileparts(moving_fn);
            moving_reg_channel = app.id_reg_channel(name);
            [~,name,~] = fileparts(fixed_fn);
            fixed_reg_channel = app.id_reg_channel(name);

            findFiducials(fixed_fn, moving_fn, fixed_reg_channel, moving_reg_channel, app)
            
        end

        % Button pushed function: RegButton
        function RegButtonPushed(app, event)
         
            reg_sel_idx = find(strcmp(app.RegTable.RowName, app.reg_sel));
            if app.T1(reg_sel_idx,:).Registered == 1
                msgbox("This image either does not need to be registered or has already been registered")
                return
            else
                dlg = uiprogressdlg(app.UIFigure,'Title','Registration Progress',...
                    'Message','(0/1 files) Registering...');
                dlg.Value = 0;
                file_frac = '(0/1 files)';
                
                moving_fn = app.build_img_f(app.reg_sel, 'stitch');
                itxBspline2D(moving_fn, dlg, file_frac)

                app.T1(reg_sel_idx,:).Registered = 1;
                update_tables(app)
                write_tables(app)

                dlg.Value = 1;
                close(dlg)
            end


        end

        % Button pushed function: UnmixParamButton
        function UnmixParamButtonPushed(app, event)
            unmix_param_sel_idx = find(strcmp(app.UnmixParamTable.RowName, app.unmix_param_sel));
            if app.T2(unmix_param_sel_idx,:).("Unmix Settings") == 1
                msgbox("Unmixing parameters have already been set for this file")
                return
            end

            img_f = build_img_f(app, app.unmix_param_sel, 'reg');

            if isempty(img_f)
                msgbox("Selected registered image does not exist")
                return
            end

            setUnmixParams(img_f, app, 'find_params')
        end

        % Button pushed function: UnmixButton
        function UnmixButtonPushed(app, event)
            unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, app.unmix_sel));
            if app.T1(unmix_sel_idx,:).Unmixed == 1
                msgbox("This file has already been unmixed")
                return
            end

            img_f = build_img_f(app, app.unmix_param_sel, 'reg');

            setUnmixParams(img_f, app, 'write')
        end

        % Button pushed function: UnmixAllButton
        function UnmixAllButtonPushed(app, event)
            to_unmix = ~app.T1.Unmixed;
            if sum(to_unmix) < 1
                msgbox("All images have already been unmixed")
                return
            end

            unmix_sels = app.UnmixTable.RowName(to_unmix);
            
            max_files = sum(to_unmix);
            file_frac = strcat('(0/',string(max_files),' files)');
            d = uiprogressdlg(app.UIFigure,'Title','Unmixing Progress',...
                    'Message',strcat(file_frac,' Unmixing...'));
            d.Value = 0;
            
            for i=1:length(unmix_sels)

                file_frac = strcat('(',string(i-1),'/',string(max_files),' files)');
                d.Message = strcat(file_frac,' Unmixing...');

                img_f = build_img_f(app, unmix_sels(i), 'reg');
                unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, unmix_sels(i)));
                
                setUnmixParams(img_f, app, 'write')

                app.T1(unmix_sel_idx,:).Unmixed = 1;
                update_tables(app)
                write_tables(app)

                d.Value = i/max_files;
                
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            process_sel_idx = find(strcmp(app.ProcessedTable.RowName, app.process_sel));

            if app.T1(process_sel_idx,:).Final == 1
                msgbox("This file has already been fully processed")
                return
            end
            
            img_f = build_img_f(app, app.process_sel, 'unmix');

            if isempty(img_f)
                msgbox("Selected unmixed image does not exist")
                return
            end

            process_final(img_f)

            app.T1(process_sel_idx,:).Final = 1;
            update_tables(app)
            write_tables(app)


        end

        % Cell selection callback: StitchTable
        function StitchTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.stitch_sel = app.StitchTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: FiducialsTable
        function FiducialsTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.fiducials_sel = app.FiducialsTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: UnmixParamTable
        function UnmixParamTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.unmix_param_sel = app.UnmixParamTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: RegTable
        function RegTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.reg_sel = app.RegTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: UnmixTable
        function UnmixTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.unmix_sel = app.UnmixTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: ProcessedTable
        function ProcessedTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.process_sel = app.ProcessedTable.RowName{row_idx};
            else
                return
            end
        end

        % Button pushed function: memMonitorButton
        function memMonitorButtonPushed(app, event)
            memMonitor()
        end

        % Value changed function: FinalSwitch, LipoSwitch, RegSwitch, 
        % ...and 3 other components
        function ProcessingSwitchValueChanged(app, event)
            app.update_pipeline()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.FilesTable = uitable(app.MainTab);
            app.FilesTable.ColumnName = {'Stitched'; 'Registered'; 'Unmixed'; 'Masked'; 'Processed'};
            app.FilesTable.RowName = {};
            app.SettingsTable = uitable(app.MainTab);
            app.SettingsTable.ColumnName = {'Fiducials'; 'Unmix Settings'};
            app.SettingsTable.RowName = {};
            app.StitchingTab = uitab(app.TabGroup);
            app.StitchingTab.Title = 'Stitching';
            app.StitchAllButton = uibutton(app.StitchingTab, 'push');
            app.StitchAllButton.ButtonPushedFcn = createCallbackFcn(app, @StitchAllButtonPushed, true);
            app.StitchAllButton.Text = 'Stitch all files';
            app.StitchButton = uibutton(app.StitchingTab, 'push');
            app.StitchButton.ButtonPushedFcn = createCallbackFcn(app, @StitchButtonPushed, true);
            app.StitchButton.Text = 'Stitch this file';
            app.StitchTable = uitable(app.StitchingTab);
            app.StitchTable.ColumnName = {'Stitched'};
            app.StitchTable.RowName = {};
            app.StitchTable.CellSelectionCallback = createCallbackFcn(app, @StitchTableCellSelection, true);
            app.StitchRoundLabel = uilabel(app.StitchingTab);
            app.StitchRoundLabel.Text = 'StitchRoundLabel';
            app.RegTab = uitab(app.TabGroup);
            app.RegTab.Title = 'Registration';
            app.FiducialsTable = uitable(app.RegTab);
            app.FiducialsTable.ColumnName = {'Fiducials'};
            app.FiducialsTable.RowName = {};
            app.FiducialsTable.CellSelectionCallback = createCallbackFcn(app, @FiducialsTableCellSelection, true);
            app.RegTable = uitable(app.RegTab);
            app.RegTable.ColumnName = {'Registered'};
            app.RegTable.RowName = {};
            app.RegTable.CellSelectionCallback = createCallbackFcn(app, @RegTableCellSelection, true);
            app.FiducialsButton = uibutton(app.RegTab, 'push');
            app.FiducialsButton.ButtonPushedFcn = createCallbackFcn(app, @FiducialsButtonPushed, true);
            app.FiducialsButton.Text = 'Find fiducials for this file';
            app.RegRoundLabel = uilabel(app.RegTab);
            app.RegRoundLabel.Text = 'RegRoundLabel';
            app.RegButton = uibutton(app.RegTab, 'push');
            app.RegButton.ButtonPushedFcn = createCallbackFcn(app, @RegButtonPushed, true);
            app.RegButton.Text = 'Register this file';
            app.UseDefaultParametersCheckBox = uicheckbox(app.RegTab);
            app.UseDefaultParametersCheckBox.Text = 'Use Default Parameters';
            app.UseDefaultParametersCheckBox.Value = true;
            app.LipofuscinRemovalTab = uitab(app.TabGroup);
            app.LipofuscinRemovalTab.Title = 'Lipofuscin Removal';
            app.LipoRoundLabel = uilabel(app.LipofuscinRemovalTab);
            app.LipoRoundLabel.Text = 'RegRoundLabel';
            app.SpectralUnmixingTab = uitab(app.TabGroup);
            app.SpectralUnmixingTab.Title = 'Spectral Unmixing';
            app.UnmixRoundLabel = uilabel(app.SpectralUnmixingTab);
            app.UnmixRoundLabel.Text = 'RegRoundLabel';
            app.UnmixParamTable = uitable(app.SpectralUnmixingTab);
            app.UnmixParamTable.ColumnName = {'Unmix Parameters'};
            app.UnmixParamTable.RowName = {};
            app.UnmixParamTable.CellSelectionCallback = createCallbackFcn(app, @UnmixParamTableCellSelection, true);
            app.UnmixTable = uitable(app.SpectralUnmixingTab);
            app.UnmixTable.ColumnName = {'Unmixed'};
            app.UnmixTable.RowName = {};
            app.UnmixTable.CellSelectionCallback = createCallbackFcn(app, @UnmixTableCellSelection, true);
            app.UnmixParamButton = uibutton(app.SpectralUnmixingTab, 'push');
            app.UnmixParamButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixParamButtonPushed, true);
            app.UnmixParamButton.Text = 'Set unmixing parameters for this file';
            app.UnmixButton = uibutton(app.SpectralUnmixingTab, 'push');
            app.UnmixButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixButtonPushed, true);
            app.UnmixButton.Text = 'Unmix this file';
            app.UnmixAllButton = uibutton(app.SpectralUnmixingTab, 'push');
            app.UnmixAllButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixAllButtonPushed, true);
            app.UnmixAllButton.Text = 'Unmix all files';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.FinalTab = uitab(app.TabGroup);
            app.FinalTab.Title = 'Final Processing';
            app.ProcessedTable = uitable(app.FinalTab);
            app.ProcessedTable.ColumnName = {'Processed'};
            app.ProcessedTable.RowName = {};
            app.ProcessedTable.CellSelectionCallback = createCallbackFcn(app, @ProcessedTableCellSelection, true);
            app.ProcessButton = uibutton(app.FinalTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process this file';
            app.RegistrationRoundLabel = uilabel(app.UIFigure);
            app.RegistrationRoundLabel.Text = 'Registration Round';
            app.RegRoundDropDown = uidropdown(app.UIFigure);
            app.RegRoundDropDown.Items = {};
            app.RegRoundDropDown.Value = {};
            app.ExpDirButton = uibutton(app.UIFigure, 'push');
            app.ExpDirButton.ButtonPushedFcn = createCallbackFcn(app, @ExpDirButtonPushed, true);
            app.ExpDirButton.Text = 'Change Experiment Directory';
            app.DirTree = uitree(app.UIFigure);
            app.DirTree.SelectionChangedFcn = createCallbackFcn(app, @DirTreeSelectionChanged, true);
            app.ExperimentDirectoryNode = uitreenode(app.DirTree);
            app.ExperimentDirectoryNode.Text = 'Experiment Directory';
            app.memMonitorButton = uibutton(app.UIFigure, 'push');
            app.memMonitorButton.ButtonPushedFcn = createCallbackFcn(app, @memMonitorButtonPushed, true);
            app.memMonitorButton.Text = 'Launch Memory Monitor';
            app.ProcessingPipelinePanel = uipanel(app.UIFigure);
            app.ProcessingPipelinePanel.Title = 'Processing Pipeline';
            app.StitchLamp = uilamp(app.ProcessingPipelinePanel);
            app.RegLamp = uilamp(app.ProcessingPipelinePanel);
            app.LipoLamp = uilamp(app.ProcessingPipelinePanel);
            app.UnmixLamp = uilamp(app.ProcessingPipelinePanel);
            app.SegLamp = uilamp(app.ProcessingPipelinePanel);
            app.Image = uiimage(app.ProcessingPipelinePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.StitchingLabel = uilabel(app.ProcessingPipelinePanel);
            app.StitchingLabel.Text = 'Stitching';
            app.RegistrationLabel = uilabel(app.ProcessingPipelinePanel);
            app.RegistrationLabel.Text = 'Registration';
            app.StitchSwitch = uiswitch(app.ProcessingPipelinePanel, 'toggle');
            app.StitchSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.StitchSwitch.Enable = 'off';
            app.StitchSwitch.Value = 'On';
            app.LipoGoneLabel = uilabel(app.ProcessingPipelinePanel);
            app.LipoGoneLabel.Text = 'LipoGone';
            app.UnmixingLabel = uilabel(app.ProcessingPipelinePanel);
            app.UnmixingLabel.Text = 'Unmixing';
            app.SegmentationLabel = uilabel(app.ProcessingPipelinePanel);
            app.SegmentationLabel.Text = 'Segmentation';
            app.Image_2 = uiimage(app.ProcessingPipelinePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.RegSwitch = uiswitch(app.ProcessingPipelinePanel, 'toggle');
            app.RegSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.RegSwitch.Value = 'On';
            app.LipoSwitch = uiswitch(app.ProcessingPipelinePanel, 'toggle');
            app.LipoSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.LipoSwitch.Value = 'On';
            app.UnmixSwitch = uiswitch(app.ProcessingPipelinePanel, 'toggle');
            app.UnmixSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.UnmixSwitch.Value = 'On';
            app.Image_3 = uiimage(app.ProcessingPipelinePanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.SegSwitch = uiswitch(app.ProcessingPipelinePanel, 'toggle');
            app.SegSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.SegSwitch.Value = 'On';
            app.Image_4 = uiimage(app.ProcessingPipelinePanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.Image_5 = uiimage(app.ProcessingPipelinePanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.FinalLamp = uilamp(app.ProcessingPipelinePanel);
            app.FinalLabel = uilabel(app.ProcessingPipelinePanel);
            app.FinalLabel.Text = 'Final';
            app.FinalSwitch = uiswitch(app.ProcessingPipelinePanel, 'toggle');
            app.FinalSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.FinalSwitch.Value = 'On';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Status Tables', 'Files'};
            app.Switch.Value = 'Status Tables';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCRprocess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end