classdef manual_masks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        PreloadRGBZstackButton  matlab.ui.control.Button
        zSlider                 matlab.ui.control.Slider
        zPlaneLabel             matlab.ui.control.Label
        zSliderLabel            matlab.ui.control.Label
        ChansPanel              matlab.ui.container.Panel
        LoadImButton            matlab.ui.control.Button
        ImPanel                 matlab.ui.container.Panel
        ImAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        ims_f
        masksIm_f
        labelIms_f
        masksIm
        labelIms = {}
        fullIm
        preloadedRGBIm
        zPlaneIm
        imObj
        
        numChans
        numLabelIms
        chanStr = {}

        chansGrid
        chansLabelEdit = {}
        chansColorButton = {}
        chansMinMaxLabel = {}
        chansMinMaxSlider = {}
        chansEnableCheckbox = {}
        chansAutoLim = {}

        componentsColor = [0.9 0.9 0.9]
        zPlane = 1

        num_rows
        num_cols
        num_planes

        PanAnchor
        IsPanning = false
        PanAxes
        Preloaded = false
        ROIMode = false

    end
    
    methods (Access = private)
        
        function [im_local, im_num_chans_local] = loadIm(~, filepath)

            [im_local, im_info_local] = read_tiff(filepath);
            
            % evil, long if-else tree for taking in any kind of input image
            if ismatrix(im_local) % 2D image, 1 chan, 1 plane, easy

                im_num_chans_local = 1;

            elseif ~isempty(im_info_local)

                [chans, ~, ~, data_found_dict] = parse_im_info(filepath);

                if data_found_dict('chans_found') == true
                    im_num_chans_local = chans; 
                else
                    im_num_chans_local = 1;
                end

                assert(im_num_chans_local == 1, ...
                    'Number of channels in each image must be 1, got %g.', ...
                    im_num_chans_local)

            end
    
        end

        function createChannelControls(app)
        
            % Outer grid: one column per channel, all equal width, spans full panel
            app.chansGrid = uigridlayout(app.ChansPanel, [1, app.numChans]);
            app.chansGrid.ColumnWidth = repmat({'1x'}, 1, app.numChans);
            app.chansGrid.RowHeight = {'1x'};
            app.chansGrid.Padding = [5 5 5 5];
            app.chansGrid.ColumnSpacing = 2;
        
            for i = 1:app.numChans
                % Nested grid for one channel's stacked controls
                % Rows: label | color button | min row | max row | auto/enable row
                chGrid = uigridlayout(app.chansGrid, [5, 1]);
                chGrid.RowHeight = {'fit', '2x', '1x', '1x', '1x'};
                chGrid.RowSpacing = 3;
                chGrid.Padding = [2 2 2 2];
                chGrid.Layout.Row = 1;
                chGrid.Layout.Column = i;
        
                % Channel label
                app.chansLabelEdit{i} = uieditfield(chGrid, 'text', ...
                    'Value', app.chanStr{i}, ...
                    'BackgroundColor', [1 1 1], ...
                    'HorizontalAlignment', 'center', ...
                    'FontSize', 18, ...
                    'FontWeight', 'bold');
                app.chansLabelEdit{i}.Layout.Row = 1;
        
                % Color swatch button
                app.chansColorButton{i} = uibutton(chGrid, 'push', ...
                    'Text', '', ...
                    'BackgroundColor', rand(1,3), ...
                    'UserData', 'colorButton', ...
                    'ButtonPushedFcn', @(src,evt) app.colorButtonFcn(src, evt, i));
                app.chansColorButton{i}.Layout.Row = 2;
        
                 % Numeric readout of current [min max] (slider ticks alone are coarse)
                lbl = uilabel(chGrid, 'Text', '0 / 255', ...
                    'BackgroundColor', app.componentsColor, ...
                    'HorizontalAlignment', 'center', 'FontSize', 18);
                lbl.Layout.Row = 3;
                app.chansMinMaxLabel{i} = lbl;
        
                % Range slider replaces CminEdit/CmaxEdit
                sld = uislider(chGrid, 'range');
                sld.Limits = [0 255];
                sld.Value = [0 255];
                sld.Enable = 'off';   % matches old 'Enable','Off' until Auto is unchecked
                sld.ValueChangedFcn = @(src,evt) app.minMaxSliderChanged(src, evt, i);
                sld.Layout.Row = 4;
                app.chansMinMaxSlider{i} = sld;
        
                % Bottom row: On checkbox + Auto toggle side by side
                bottomGrid = uigridlayout(chGrid, [1, 2]);
                bottomGrid.ColumnWidth = {'1x', '1x'};
                bottomGrid.Padding = [0 0 0 0];
                bottomGrid.ColumnSpacing = 2;
                bottomGrid.Layout.Row = 5;
        
                app.chansEnableCheckbox{i} = uicheckbox(bottomGrid, ...
                    'Text', 'On', ...
                    'FontSize', 18, ...
                    'Value', true, ...
                    'UserData', 'enableCheckbox', ...
                    'ValueChangedFcn', @(src,evt) app.updateImage(src, evt));

                autoChk = uicheckbox(bottomGrid, 'Text', 'Auto', 'FontSize', 18, ...
                    'Value', true, ...
                    'ValueChangedFcn', @(src,evt) app.enableLims(src, evt, i));
                autoChk.Layout.Column = 2;
                app.chansAutoLim{i} = autoChk;
        
            end

        end

        function colorButtonFcn(app, src, event, chan)

            newclr = uisetcolor(app.chansColorButton{chan}.BackgroundColor);
            
            % uisetcolor returns 0 if the user cancels the dialog
            if isequal(newclr, 0)
                return
            end
            
            app.chansColorButton{chan}.BackgroundColor = newclr;
            app.updateImage(src, event)

        end
        
        function initZPlaneIm(app)

            app.updateZPlaneIm()

            app.zSlider.Limits = [1, app.num_planes];
            app.zSlider.MinorTicks = 1:app.num_planes;
            app.zSlider.Value = app.zPlane;
            app.zPlaneLabel.Text = num2str(app.zPlane);

        end

        function updateZPlaneIm(app)

            app.zPlaneIm = squeeze(app.fullIm(:,:,app.zPlane,:));
  
            for i=1:app.numChans
                Limits = [min(app.zPlaneIm(:,:,i), [], 'all');
                    max(app.zPlaneIm(:,:,i), [], 'all')];
                app.chansMinMaxSlider{i}.Limits = Limits;
            end

        end

        function scaledIm = getScaledImage(app, chan, getLims)
            
            scaledIm = app.zPlaneIm(:,:,chan);

            if getLims
                lims = app.getChanLims(chan, scaledIm);
            else
                lims = app.chansMinMaxSlider{chan}.Value;
            end
            
            scaledIm = (scaledIm - lims(1)) ./ (lims(2)-lims(1));
            scaledIm = clip(scaledIm, 0, 1);

        end

        function lims = getChanLims(app, chan, im)
            
            if get(app.chansAutoLim{chan}, 'Value') == false

                cmin = app.chansMinMaxSlider{chan}.Value(1);
                cmax = app.chansMinMaxSlider{chan}.Value(2);

            else

                P = qprctile(im(:), [0.1 99.995]);
                cmin = P(1);
                cmax = P(2);
                
                if cmax <= cmin
                    cmax = cmin + 1;
                end
                
                app.chansMinMaxSlider{chan}.Value(1) = cmin;
                app.chansMinMaxSlider{chan}.Value(2) = cmax;

            end
            
            lims = [cmin cmax];

        end

        function updateImage(app, varargin)

            getLims = repelem(true,app.numChans);
            if ~isempty(varargin)
                if strcmp(varargin{1}.UserData,'enableCheckbox') 
                    getLims = repelem(false,app.numChans);
                elseif strcmp(varargin{1}.UserData,'colorButton') 
                    getLims = repelem(false,app.numChans);
                end
            end
            
            im = zeros(app.num_rows, app.num_cols, 3);

            for i = 1:app.numChans

                if app.chansEnableCheckbox{i}.Value == false
                    continue;
                end
                
                clr = app.chansColorButton{i}.BackgroundColor;
                scim = app.getScaledImage(i, getLims(i));
                
                for j = 1:3
                    im(:,:,j) = im(:,:,j) + scim.*clr(j);
                end
   
            end

            app.imObj.CData = im;

        end

        function inAxes = isMouseOverAxes(app, ax)

            mousePos = app.UIFigure.CurrentPoint;     % [x y] in pixels, from bottom-left
        
            axPos = getpixelposition(ax, true);       % true = isrecursive (relative to figure) [left bottom width height]
            inAxes = mousePos(1) >= axPos(1) && ...
                     mousePos(1) <= axPos(1) + axPos(3) && ...  % inside x
                     mousePos(2) >= axPos(2) && ...
                     mousePos(2) <= axPos(2) + axPos(4);  % inside y
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test)
            
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])

            app.UIFigure.WindowState = 'maximized';
            
            app.imObj = image(app.ImAxes,[]);
            axis(app.ImAxes,"off")
            axis(app.ImAxes,"image")
            disableDefaultInteractivity(app.ImAxes)
            app.ImAxes.Toolbar.Visible = 'off';
            
            app.ims_f = {'D:\2026-01-16_MC_SC_17\post\core_output\s01L\r0_NT647.tif';
                'D:\2026-01-16_MC_SC_17\post\core_output\s01L\r0_EGFP488.tif';
                'D:\2026-01-16_MC_SC_17\post\core_output\s01L\r0_dTom561.tif'};
            app.masksIm_f = app.ims_f{1};
            app.labelIms_f = app.ims_f(2:end);

            app.numLabelIms = length(app.labelIms_f);
            app.numChans = 1 + app.numLabelIms;
            for i=1:app.numChans
                [~,name,~] = fileparts(app.ims_f{i});
                app.chanStr{i} = name(4:end);
            end

            app.createChannelControls()
            

        end

        % Button pushed function: LoadImButton
        function LoadImButtonPushed(app, event)
            
            Message = 'Loading masks image...';
            d = uiprogressdlg(app.UIFigure,'Message',Message,'Indeterminate','on');

            [app.masksIm, ~] =  app.loadIm(app.masksIm_f);

            Message = 'Loading label images...';
            d.Message = Message;

            for i=1:length(app.labelIms_f)
                app.labelIms{i} = app.loadIm(app.labelIms_f{i});
            end

            [app.num_rows, app.num_cols, app.num_planes] = size(app.masksIm);
            app.zPlane = round(app.num_planes / 2);
            app.fullIm = zeros(app.num_rows, app.num_cols, app.num_planes, app.numChans, "double");

            app.fullIm(:,:,:,1) = double(app.masksIm);
            for i=1:app.numLabelIms
                app.fullIm(:,:,:,i+1) = double(app.labelIms{i});
            end

            app.initZPlaneIm()
            app.updateImage()
            
            delete(d)

        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            
            app.zPlane = uint8(app.zSlider.Value);
            app.zPlaneLabel.Text = num2str(app.zPlane);

            if app.Preloaded
                app.imObj.CData = squeeze(app.preloadedRGBIm(:,:,app.zPlane,:));
            else
                app.updateZPlaneIm()
                app.updateImage()
            end
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            
            ax = app.ImAxes;
            inAxes = app.isMouseOverAxes(ax);
            imgSize = size(app.imObj.CData);

            if ~inAxes
                return
            end

            % --- Zoom logic ---
            xlim   = get(ax, 'XLim');
            ylim   = get(ax, 'YLim');
            cp     = ax.CurrentPoint;
            mouseX = cp(1,1);
            mouseY = cp(1,2);
        
            zoomFactor = 1.15;
            if event.VerticalScrollCount < 0
                scale = 1 / zoomFactor;   % zoom in
            else
                scale = zoomFactor;        % zoom out
            end

            newXLim = mouseX + (xlim - mouseX) * scale;
            newYLim = mouseY + (ylim - mouseY) * scale;
            
            newXLim = max(0.5, min(imgSize(2)+0.5, newXLim));
            newYLim = max(0.5, min(imgSize(1)+0.5, newYLim));

            set(ax, 'XLim', newXLim, ...
                    'YLim', newYLim);

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            if app.ROIMode
                return
            end

            switch app.UIFigure.SelectionType

                case 'normal'

                    ax = app.ImAxes;
                    inAxes = app.isMouseOverAxes(ax);
        
                    if ~inAxes
                        return
                    end
        
                    cp = ax.CurrentPoint;
                    x = cp(1,1);
                    y = cp(1,2);

                    app.PanAnchor  = [x, y];
                    app.IsPanning  = true;

                case 'alt'

                    app.ROIMode = true;
                    roi = drawpolygon(app.ImAxes);
                    app.UIFigure.Pointer = 'watch';
                    drawnow
                    mask = createMask(roi);
                    bw = activecontour(app.masksIm(:,:,app.zPlane),mask,20,'edge','ContractionBias',0);
                    visboundaries(app.ImAxes,bw,'Color','r'); 
                    delete(roi)
                    app.ROIMode = false;
                    app.UIFigure.Pointer = 'arrow';

            end

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.IsPanning = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            if ~app.IsPanning
                return; 
            end

            cp = app.ImAxes.CurrentPoint;
            dx = app.PanAnchor(1) - cp(1,1);
            dy = app.PanAnchor(2) - cp(1,2);
            app.ImAxes.XLim = app.ImAxes.XLim + dx;
            app.ImAxes.YLim = app.ImAxes.YLim + dy;

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)

        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)

        end

        % Button pushed function: PreloadRGBZstackButton
        function PreloadRGBZstackButtonPushed(app, event)
            
            Message = 'Preloading RGB Image...';
            d = uiprogressdlg(app.UIFigure,'Message',Message,'Indeterminate','off');

            app.preloadedRGBIm = zeros(app.num_rows, app.num_cols, app.num_planes, 3, 'double');
            
            % switch all channels to auto limits
            for i=1:app.numChans
                app.chansAutoLim{i}.Value = true;
            end

            for i = 1:app.num_planes

                d.Value = i / app.num_planes;
                d.Message = sprintf('Preloading RGB Image... (%d / %d)', i, app.num_planes);
                app.zPlane = i;
                app.updateZPlaneIm()

                for j = 1:app.numChans
                
                    clr = app.chansColorButton{j}.BackgroundColor;
                    scim = app.getScaledImage(j, true);
                    
                    for k = 1:3
                        app.preloadedRGBIm(:,:,i,k) = app.preloadedRGBIm(:,:,i,k) + scim.*clr(k);
                    end

                end
   
            end

            app.Preloaded = true;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImPanel = uipanel(app.GridLayout);
            app.ImPanel.Title = 'Image';
            app.ImAxes = uiaxes(app.ImPanel);
            app.ImAxes.XTick = [];
            app.ImAxes.YTick = [];
            app.LoadImButton = uibutton(app.GridLayout, 'push');
            app.LoadImButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImButtonPushed, true);
            app.LoadImButton.Text = 'Load Image';
            app.ChansPanel = uipanel(app.GridLayout);
            app.ChansPanel.Title = 'Channels';
            app.zSliderLabel = uilabel(app.GridLayout);
            app.zSliderLabel.Text = 'Z-plane:';
            app.zPlaneLabel = uilabel(app.GridLayout);
            app.zPlaneLabel.Text = '1';
            app.zSlider = uislider(app.GridLayout);
            app.zSlider.Limits = [1 100];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zSlider.Value = 75.2322384673338;
            app.PreloadRGBZstackButton = uibutton(app.GridLayout, 'push');
            app.PreloadRGBZstackButton.ButtonPushedFcn = createCallbackFcn(app, @PreloadRGBZstackButtonPushed, true);
            app.PreloadRGBZstackButton.Text = 'Preload RGB z-stack';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_masks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end