classdef quality_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        RegistrationChannelsPanel  matlab.ui.container.Panel
        RegChannelAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        masks_tags = {'masks'};
        reg_channels_tags = {'neur','nt'}
        primary_reg_channel_tag = 'r1';
        
        im_dir 
        f_masks_im
        f_reg_channels_ims
        f_primary_reg_channel_im
        f_secondary_reg_channel_ims

        masks_im
        primary_reg_channel_im
        secondary_reg_channel_ims

        masks
        num_masks
        masks_stats

        im_size
        num_reg_channel_ims

    end
    
    methods (Access = private)
        
        function [ROI, ROI_mask] = extract_ROI(app,im,mask,plane)

            arguments
                app
                im
                mask
                plane = []
            end
            
            bbox = num2cell(app.masks_stats{string(mask),"BoundingBox"});
            [ulf_x, ulf_y, ulf_z, width_x, width_y, width_z] = bbox{:};

            min1 = ceil(ulf_y);
            min2 = ceil(ulf_x);
            min3 = ceil(ulf_z);

            max1 = floor(ulf_y + width_y);
            max2 = floor(ulf_x + width_x);
            max3 = floor(ulf_z + width_z);
            
            % determine if any of the indices are out of bounds
            out_of_bounds = false;
            if any([min1 < 1, min2 < 1, min3 < 1])
                out_of_bounds = true;
            end
            if any([max1 > app.im_size(1), max2 > app.im_size(2), max3 > app.im_size(3)])
                out_of_bounds = true;
            end

            switch out_of_bounds

                case false

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;
                    ROI = im(ind1, ind2, ind3);
                    ROI_mask = app.masks_im(ind1, ind2, ind3);

                case true

                    prepad1 = max(1-min1,0);
                    postpad1 = max(max1-app.im_size(1),0);
                    prepad2 = max(1-min2,0);
                    postpad2 = max(max2-app.im_size(2),0);
                    prepad3 = max(1-min3,0);
                    postpad3 = max(max3-app.im_size(3),0);

                    min1 = min1 + prepad1;
                    max1 = max1 - postpad1;
                    min2 = min2 + prepad2;
                    max2 = max2 - postpad2;
                    min3 = min3 + prepad3;
                    max3 = max3 - postpad3;

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;
                    
                    ROI = im(ind1, ind2, ind3);
                    ROI = padarray(ROI,[prepad1 prepad2 prepad3],"pre");
                    ROI = padarray(ROI,[postpad1 postpad2 postpad3],"post");

                    ROI_mask = app.masks_im(ind1, ind2, ind3);
                    ROI_mask = padarray(ROI_mask,[prepad1 prepad2 prepad3],"pre");
                    ROI_mask = padarray(ROI_mask,[postpad1 postpad2 postpad3],"post");

            end

            if ~isempty(plane)
                ROI = ROI(:,:,plane);
                ROI_mask = ROI_mask(:,:,plane);
            end
         
        end

        function principal_plane = find_principal_plane(~,ROI,ROI_mask,mask)

            num_planes = size(ROI,3);

            if num_planes == 1
                principal_plane = 1;
                return
            end

            binary_mask = (ROI_mask == mask);
            plane_means = zeros(num_planes,1);
            plane_sizes = zeros(num_planes,1);

            for i=1:num_planes
                ROI_plane = ROI(:,:,i);
                binary_mask_plane = binary_mask(:,:,i);
                plane_means(i) = mean(ROI_plane(binary_mask_plane));
                plane_sizes(i) = sum(binary_mask_plane,"all");
            end

            plane_means = normalize(plane_means,"zscore");
            plane_sizes = normalize(plane_sizes,"zscore");
            scores = mean(horzcat(plane_means,plane_sizes),2);
            [~,I] = max(scores);

            principal_plane = I;

        end

        function tiles = make_reg_channels_tiles(app,mask)

            principal_plane = app.masks_stats{string(mask),"PrincipalPlane"};

            primary_reg_im = app.extract_ROI(app.primary_reg_channel_im,mask,principal_plane);
            primary_reg_im = rescale(primary_reg_im,0,65535);

            vol = zeros(size(primary_reg_im,1),size(primary_reg_im,2),app.num_reg_channel_ims);
            vol(:,:,1) = primary_reg_im;

            for i=1:app.num_reg_channel_ims-1
                secondary_reg_im = app.extract_ROI(app.secondary_reg_channel_ims{i},mask,principal_plane);
                secondary_reg_im = rescale(secondary_reg_im,0,65535);
                vol(:,:,i+1) = secondary_reg_im;
            end

            tiles = imtile(vol,BorderSize=[1 1]);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % add subdirectories with needed functions
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            addpath(['./' 'assets'])

            movegui(app.UIFigure,'north')
            axis(app.RegChannelAxes,"off")
            axis(app.RegChannelAxes,"image")

            app.im_dir = 'C:\Users\jpv88\Desktop\s04';
            
            % get image file names
            listing = dir(app.im_dir);
            f_names = {listing.name};
            TF_im = contains(f_names,{'.tif','.tiff'},'IgnoreCase',true);
            im_files = f_names(TF_im);
            
            % get masks image file name
            TF_mask = contains(im_files,app.masks_tags);
            app.f_masks_im = im_files(TF_mask);
            app.f_masks_im = app.f_masks_im{1};
            
            % get registration channel image file names
            TF_reg_channels = contains(im_files,app.reg_channels_tags);
            app.f_reg_channels_ims = im_files(TF_reg_channels);
            app.num_reg_channel_ims = length(app.f_reg_channels_ims);
            
            % get primary registration channel image name
            TF_primary_reg_channel = contains(app.f_reg_channels_ims,app.primary_reg_channel_tag);
            app.f_primary_reg_channel_im = app.f_reg_channels_ims(TF_primary_reg_channel);
            app.f_primary_reg_channel_im = app.f_primary_reg_channel_im{1};

            % get secondary registration channel image names
            app.f_secondary_reg_channel_ims = app.f_reg_channels_ims(~TF_primary_reg_channel);

            % load all images
            app.masks_im = read_tiff(fullfile(app.im_dir,app.f_masks_im));
            app.primary_reg_channel_im = read_tiff(fullfile(app.im_dir,app.f_primary_reg_channel_im));
            app.secondary_reg_channel_ims = cell(app.num_reg_channel_ims-1,1);
            for i=1:app.num_reg_channel_ims-1
                app.secondary_reg_channel_ims{i} = read_tiff(fullfile(app.im_dir,app.f_secondary_reg_channel_ims{i}));
            end

            % prepare masks stuff
            app.masks = unique(app.masks_im);
            app.masks(1) = [];
            app.num_masks = length(app.masks);
            app.masks_stats = regionprops3(app.masks_im,{"BoundingBox","Centroid"});
            A = table2array(app.masks_stats);
            TF = isnan(A);
            TF = any(TF,2);
            TF = ~TF;
            app.masks_stats = app.masks_stats(TF,:);
            app.masks_stats.Properties.RowNames = string(app.masks);

            app.im_size = size(app.primary_reg_channel_im);
            
            % find principal planes of each ROI
            principal_planes = zeros(app.num_masks,1);
            for i=1:app.num_masks
                mask = app.masks(i);
                [ROI, ROI_mask] = app.extract_ROI(app.primary_reg_channel_im,mask);
                principal_planes(i) = app.find_principal_plane(ROI,ROI_mask,mask);
            end
            app.masks_stats = addvars(app.masks_stats,principal_planes,'After','Centroid','NewVariableNames',{'PrincipalPlane'});

            tiles = app.make_reg_channels_tiles(app.masks(5));
            imshow(tiles,[0 65535],Parent=app.RegChannelAxes)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RegistrationChannelsPanel = uipanel(app.GridLayout);
            app.RegistrationChannelsPanel.Title = 'Registration Channels';
            app.RegChannelAxes = uiaxes(app.RegistrationChannelsPanel);
            app.RegChannelAxes.XTick = [];
            app.RegChannelAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quality_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end